classdef imageconversion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ImageLoadLocationEditFieldLabel  matlab.ui.control.Label
        ImageLoadLocationEditField  matlab.ui.control.EditField
        SelectFolderButton          matlab.ui.control.Button
        ConvertImagesButton         matlab.ui.control.Button
        ExitButton                  matlab.ui.control.Button
        SelectFileTypetoConvertImagesTooDropDownLabel  matlab.ui.control.Label
        SelectFileTypetoConvertImagesTooDropDown  matlab.ui.control.DropDown
        ImageSaveLocationEditFieldLabel  matlab.ui.control.Label
        ImageSaveLocationEditField  matlab.ui.control.EditField
        SelectFolderButton_2        matlab.ui.control.Button
    end

    
    properties (Access = private)
        dirname %variable names to pass between buttons, this is load directory for images
        dirname2 %This is save directory for images
        type %This is the save file format
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            dirname=uigetdir;
            app.ImageLoadLocationEditField.Value=dirname;
            app.dirname=dirname;
            cd(dirname)
        end

        % Button pushed function: SelectFolderButton_2
        function SelectFolderButton_2Pushed(app, event)
            dirname2=uigetdir;
            app.ImageSaveLocationEditField.Value=dirname2;
            app.dirname2=dirname2;
        end

        % Value changed function: 
        % SelectFileTypetoConvertImagesTooDropDown
        function SelectFileTypetoConvertImagesTooDropDownValueChanged(app, event)
            type = app.SelectFileTypetoConvertImagesTooDropDown.Value;
            app.type=type;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq()
        end

        % Button pushed function: ConvertImagesButton
        function ConvertImagesButtonPushed(app, event)
         dirName = app.dirname;   %# folder path for where the files are located
files = dir(app.dirname);   %# list all files in the directory
files = {files.name}';                   % file names
%___________________________________
for i=3:length(files) %length(files) lets us get all files, . and .. register as file names in all folders (probably as a hidden file) so we start from 3
    try %Try allows us to handles errors with the path specified by catch, which is important as images as finniky and do what they please sometimes, so can throw up an error in the oddest of situations
fname={files{i}}; %files{i} contains the file extensions in the names
[A,C,T]=  imread(fname{1}); %image reads each of the files in the current directory
if length(T)==0 %Some png files have transparency, others do not (in which case length(T)=0
[A,C]=imread(fname{1}); % read the image file
h=imshow(A,C); % plot the image with the colourmap (THIS WAS THE SOURCE OF MY PROBLEM) don't forget the colourmap or it often will give you a lovely black box to look at instead of the image (since it will guess the colours based on A)
stringy=files{i}(1:end-4); %This is to get the correct file names for saving as
str2=app.dirname2; % Where we would like to save the files 
string=strcat(str2,'/',stringy,app.type); %what we would like to save the files as
saveas(gca,string) %save directory goes here
close all
else
h=imshow(A,C);
h.AlphaData=T; %As above, but this is the case with transparency (called alpha) T
stringy=files{i}(1:end-4);
str2=app.dirname2;
string=strcat(str2,'/',stringy,app.type);
saveas(gca,string) %save directory here
close all
end
    catch ME %This is for when there is an error, such as transparancy not being empty, but impossible to derive, which would usually throw an error and exit the image conversion loop
[A,C]=imread(fname{1}); %In this case we would take the path which does not require transparency information
h=imshow(A,C);
stringy=files{i}(1:end-4);
str2=app.dirname2;
string=strcat(str2,'/',stringy,app.type);
saveas(gca,string) %save directory here
close all
    end
clear A T C %Clear variables between loops
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageLoadLocationEditFieldLabel = uilabel(app.UIFigure);
            app.ImageLoadLocationEditFieldLabel.Text = 'Image Load Location';
            app.ImageLoadLocationEditField = uieditfield(app.UIFigure, 'text');
            app.ImageLoadLocationEditField.Value = 'Folder Name';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.ConvertImagesButton = uibutton(app.UIFigure, 'push');
            app.ConvertImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertImagesButtonPushed, true);
            app.ConvertImagesButton.Text = 'Convert Images';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SelectFileTypetoConvertImagesTooDropDownLabel = uilabel(app.UIFigure);
            app.SelectFileTypetoConvertImagesTooDropDownLabel.Text = 'Select File Type to Convert Images Too';
            app.SelectFileTypetoConvertImagesTooDropDown = uidropdown(app.UIFigure);
            app.SelectFileTypetoConvertImagesTooDropDown.Items = {'--Convert To--', '.svg', '.bmp', '.jpg', '.fig', '.png', '.tif'};
            app.SelectFileTypetoConvertImagesTooDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectFileTypetoConvertImagesTooDropDownValueChanged, true);
            app.SelectFileTypetoConvertImagesTooDropDown.Value = '--Convert To--';
            app.ImageSaveLocationEditFieldLabel = uilabel(app.UIFigure);
            app.ImageSaveLocationEditFieldLabel.Text = 'Image Save Location';
            app.ImageSaveLocationEditField = uieditfield(app.UIFigure, 'text');
            app.ImageSaveLocationEditField.Value = 'Folder Name';
            app.SelectFolderButton_2 = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_2Pushed, true);
            app.SelectFolderButton_2.Text = 'Select Folder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageconversion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end